classdef FinalWork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExportPanel          matlab.ui.container.Panel
        PlayLabel            matlab.ui.control.Label
        ExportButton         matlab.ui.control.Button
        HandledButton        matlab.ui.control.Button
        OriginalButton       matlab.ui.control.Button
        HandlePanel          matlab.ui.container.Panel
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        HandledLabel         matlab.ui.control.Label
        OriginalLabel        matlab.ui.control.Label
        FilterSwitch         matlab.ui.control.Switch
        FilterHzSlider       matlab.ui.control.RangeSlider
        FilterHzSliderLabel  matlab.ui.control.Label
        OffsetSlider_2       matlab.ui.control.Slider
        OffsetSlider_2Label  matlab.ui.control.Label
        HandleButton         matlab.ui.control.Button
        RecordPanel          matlab.ui.container.Panel
        RecordingLamp        matlab.ui.control.Lamp
        RecordingLampLabel   matlab.ui.control.Label
        TimesEditField       matlab.ui.control.NumericEditField
        TimesEditFieldLabel  matlab.ui.control.Label
        RecordButton         matlab.ui.control.Button
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Fs = 44100; % 采样率
        BitsPerSample = 16; % 每个样本的位数
        NumChannels = 1; % 声道数，1为单声道，2为立体声
        % RecordingTime = 5; % 录制时长，单位为秒
        y = [];
        res = [];
    end
    
    methods (Access = private)
        
        function PlayAudio(~,audioData,fs)
            % 创建音频播放器对象
            playerObj = audioplayer(audioData, fs);

            % 播放音频
            disp('开始播放...');
            play(playerObj);

            % 等待音频播放完成
            while isplaying(playerObj)
                pause(0.1);
            end
            disp('音频播放完成.');
        end

        function res = DispAveFreq(~,y,Fs)
            % 定义短时傅里叶变换的参数
            windowSize = round(Fs * 0.02); % 窗口大小为音频长度的2%
            overlap = round(windowSize * 0.5); % 重叠50%
            nfft = max(256, 2^nextpow2(windowSize)); % FFT点数

            % 计算STFT
            [S, f, ~] = spectrogram(y, windowSize, overlap, nfft, Fs);

            % 计算频谱密度
            Pyy = abs(S).^2 / nfft;

            % 计算频谱平均值
            averageFrequency = sum(Pyy .* f, 1) / sum(Pyy, 1);

            % 显示平均频率
            res = num2str(mean(averageFrequency));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % 创建录音器对象
            recObj = audiorecorder(app.Fs, app.BitsPerSample, app.NumChannels,-1);

            % 开始录制
            disp('开始录制...');
            app.RecordingLamp.Color = 'red';
            app.RecordButton.Enable = "off";
            recordblocking(recObj, app.TimesEditField.Value); % 阻塞式录制

            disp('录制完成.');
            app.RecordingLamp.Color = 'black';
            app.RecordButton.Enable = "on";
            app.y = getaudiodata(recObj);

            % 绘制源音频的波和频谱
            plot(app.UIAxes,app.y); 

            win = 20000;
            noverlap = 10000;
            nfft = 40000;
            [~,F,T,P] = spectrogram(app.y,win,noverlap,nfft,app.Fs);
        
            surf(app.UIAxes2,T,F,10*log10(P),'edgecolor','none')
        end

        % Button pushed function: HandleButton
        function HandleButtonPushed(app, event)
            % N = length(app.y); % 信号长度
            % f = (0:N-1)*(app.Fs/N); % 频率轴
            % Y = fft(app.y);
            % Pyy = Y.*conj(Y)/N; % 计算功率谱密度
            % figure;
            % plot(app.,f, Pyy);
            % title('Frequency content of the signal');
            % xlabel('Frequency (Hz)');
            % ylabel('Power');

            app.Label.Text = DispAveFreq(app,app.y,app.Fs);
            

            % 实现慢录快放和快录慢放功能
            % slow_y = resample(app.y, app.Fs, app.Fs/2); % 减慢播放速度
            % fast_y = resample(app.y, app.Fs, app.Fs*2); % 加快播放速度

            % 分割并处理音频数据
            segmentLength = app.Fs/50; % 指定的分割长度，这里为采样率Fs对应的一秒时长
            numSegments = floor(length(app.y) / segmentLength); % 计算分割的段数
            processedSegments = cell(1, numSegments); % 创建一个单元数组来存储处理后的子音频

            for i = 1:numSegments
                % 获取当前分割的音频段
                startIndex = (i - 1) * segmentLength + 1;
                endIndex = i * segmentLength;
                segment = app.y(startIndex:endIndex);

                % 使用 resample 函数对音频段进行处理
                processedSegment = resample(segment, app.Fs, round(app.Fs*app.OffsetSlider_2.Value)); % 减慢播放速度为原来的一半
    
                % 计算输入音频段的长度
                segmentSize = length(processedSegment);
    
                % 如果输入音频段长度大于指定长度，截取多余部分
                if segmentSize > segmentLength
                    modifiedSegment = processedSegment(1:segmentLength);
                else
                    % 如果输入音频段长度小于指定长度，在末尾插入零值
                    modifiedSegment = [processedSegment; zeros(segmentLength - segmentSize, 1)];
                end
                % 存储处理后的音频段
                processedSegments{i} = modifiedSegment;
            end

            % 将处理后的子音频拼接成完整的音频数据
            processed_y = cat(1, processedSegments{:});
            plot(app.UIAxes_4,processed_y); 
            app.Label_2.Text = DispAveFreq(app,processed_y,app.Fs);

            if app.FilterSwitch.Value == "On"
                % 设计数字滤波器并画出频响特性
                % [minFrq,maxFrq] = app.FilterHzSlider.Value;

                x = app.FilterHzSlider.Value;
                disp(num2str(x));

                [b, a] = butter(4, [minFrq/(app.Fs/2), maxFrq/(app.Fs/2)], 'bandpass'); % 设计带通滤波器(TODO)
    
    
                % 对语音信号进行滤波以滤除噪声
                filtered_y = filter(b, a, processed_y);
    
                plot(app.UIAxes_3,filtered_y); 
        
                app.res = filtered_y;
            else
                app.res = processed_y;
            end
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            PlayAudio(app,app.y,app.Fs);
        end

        % Button pushed function: HandledButton
        function HandledButtonPushed(app, event)
            PlayAudio(app,app.res,app.Fs);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
    
            dt = datetime("now");
            dt.Format = "dd-mmm-yyyy-HH:MM:SS";
            output_filename = "Output_"+string(dt)+".wav";
            audiowrite(output_filename,app.res,app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Filtered Signal')
            xlabel(app.UIAxes_3, 'time')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '频谱图')
            xlabel(app.UIAxes2, '/s(Time)')
            ylabel(app.UIAxes2, '/Hz')
            zlabel(app.UIAxes2, '/dB')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Offsetted Signal')
            xlabel(app.UIAxes_4, 'time')
            zlabel(app.UIAxes_4, 'Z')
            app.RecordPanel = uipanel(app.UIFigure);
            app.RecordPanel.Title = 'Record';
            app.RecordButton = uibutton(app.RecordPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.TimesEditFieldLabel = uilabel(app.RecordPanel);
            app.TimesEditFieldLabel.Text = 'Time/s';
            app.TimesEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TimesEditField.Value = 5;
            app.RecordingLampLabel = uilabel(app.RecordPanel);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.RecordPanel);
            app.HandlePanel = uipanel(app.UIFigure);
            app.HandlePanel.Title = 'Handle';
            app.HandleButton = uibutton(app.HandlePanel, 'push');
            app.HandleButton.ButtonPushedFcn = createCallbackFcn(app, @HandleButtonPushed, true);
            app.HandleButton.Text = 'Handle';
            app.OffsetSlider_2Label = uilabel(app.HandlePanel);
            app.OffsetSlider_2Label.Text = 'Offset';
            app.OffsetSlider_2 = uislider(app.HandlePanel);
            app.OffsetSlider_2.Limits = [0 10];
            app.OffsetSlider_2.MajorTicks = [0 0.5 1 1.5 2 3 5 10];
            app.OffsetSlider_2.Value = 1;
            app.FilterHzSliderLabel = uilabel(app.HandlePanel);
            app.FilterHzSliderLabel.Text = 'Filter/Hz';
            app.FilterHzSlider = uislider(app.HandlePanel, 'range');
            app.FilterHzSlider.Limits = [50 1000];
            app.FilterHzSlider.MajorTicks = [50 160 400 1000];
            app.FilterHzSlider.MajorTickLabels = {'50', '160', '400', '1000'};
            app.FilterHzSlider.Value = [50 1000];
            app.FilterSwitch = uiswitch(app.HandlePanel, 'slider');
            app.OriginalLabel = uilabel(app.HandlePanel);
            app.OriginalLabel.Text = 'Original';
            app.HandledLabel = uilabel(app.HandlePanel);
            app.HandledLabel.Text = 'Handled';
            app.Label = uilabel(app.HandlePanel);
            app.Label.Text = '-';
            app.Label_2 = uilabel(app.HandlePanel);
            app.Label_2.Text = '-';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.OriginalButton = uibutton(app.ExportPanel, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = 'Original';
            app.HandledButton = uibutton(app.ExportPanel, 'push');
            app.HandledButton.ButtonPushedFcn = createCallbackFcn(app, @HandledButtonPushed, true);
            app.HandledButton.Text = 'Handled';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlayLabel = uilabel(app.ExportPanel);
            app.PlayLabel.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalWork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end